classdef PTU_read_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FolderEditFieldLabel          matlab.ui.control.Label
        FolderEditField               matlab.ui.control.EditField
        FIleLabel                     matlab.ui.control.Label
        FileEditField                 matlab.ui.control.EditField
        SelectFolderButton            matlab.ui.control.Button
        UseLocalFolderCheckBox        matlab.ui.control.CheckBox
        SelectFileButton              matlab.ui.control.Button
        PlotGlobalTimeTraceButton     matlab.ui.control.Button
        ExportTimeTraceButton         matlab.ui.control.Button
        ExportFilePrefixEditField     matlab.ui.control.EditField
        StartTimeEditFieldLabel       matlab.ui.control.Label
        StartTimeEditField            matlab.ui.control.NumericEditField
        EndTimeEditFieldLabel         matlab.ui.control.Label
        EndTimeEditField              matlab.ui.control.NumericEditField
        gGlobalTimeTrace              matlab.ui.control.UIAxes
        ResolutionEditFieldLabel      matlab.ui.control.Label
        ResolutionEditField           matlab.ui.control.NumericEditField
        BinWidthsLabel                matlab.ui.control.Label
        TimeTraceBinWidthEditField    matlab.ui.control.NumericEditField
        gG2                           matlab.ui.control.UIAxes
        PlotG2Button                  matlab.ui.control.Button
        MaxTimeLagEditFieldLabel      matlab.ui.control.Label
        MaxTimeLagEditField           matlab.ui.control.NumericEditField
        DataFormatEditFieldLabel      matlab.ui.control.Label
        DataFormatEditField           matlab.ui.control.EditField
        gDecay                        matlab.ui.control.UIAxes
        Ch0CountsEditFieldLabel       matlab.ui.control.Label
        Ch0CountsEditField            matlab.ui.control.NumericEditField
        Ch1CountsEditFieldLabel       matlab.ui.control.Label
        Ch1CountsEditField            matlab.ui.control.NumericEditField
        OverflowCountsEditFieldLabel  matlab.ui.control.Label
        OverflowCountsEditField       matlab.ui.control.NumericEditField
        MarkerCountsEditFieldLabel    matlab.ui.control.Label
        MarkerCountsEditField         matlab.ui.control.NumericEditField
        PlotPLdecayButton             matlab.ui.control.Button
        PeriodnsLabel                 matlab.ui.control.Label
        PeriodEditField               matlab.ui.control.NumericEditField
        ExportPrefixLabel             matlab.ui.control.Label
        TotalCoindcidenceCountsLabel  matlab.ui.control.Label
        CoincidenceCountsEditField    matlab.ui.control.NumericEditField
        BinWidthnsLabel               matlab.ui.control.Label
        G2BinWidthEditField           matlab.ui.control.NumericEditField
        MincpsLabel                   matlab.ui.control.Label
        ThresholdMinCREditField       matlab.ui.control.NumericEditField
        BinWidthnsLabel_2             matlab.ui.control.Label
        PLdecayBinWidthEditField      matlab.ui.control.NumericEditField
        UseTimeMaskCheckBox           matlab.ui.control.CheckBox
        UseThresholdCheckBox          matlab.ui.control.CheckBox
        ExportPLdecayButton           matlab.ui.control.Button
        ExportG2Button                matlab.ui.control.Button
        Ch0Ch1CountsEditFieldLabel    matlab.ui.control.Label
        Ch0Ch1CountsEditField         matlab.ui.control.NumericEditField
        MaxcpsLabel                   matlab.ui.control.Label
        ThresholdMaxCREditField       matlab.ui.control.NumericEditField
        StepHeightLabel               matlab.ui.control.Label
        ThresholdStepEditField        matlab.ui.control.NumericEditField
        CustomFileCheckBox            matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        DefaultFolder='C:\DATA\Calculations\Read_PTU_v1\data'; % default folder for the files
        
        % some constants
        tyEmpty8      = hex2dec('FFFF0008');
        tyBool8       = hex2dec('00000008');
        tyInt8        = hex2dec('10000008');
        tyBitSet64    = hex2dec('11000008');
        tyColor8      = hex2dec('12000008');
        tyFloat8      = hex2dec('20000008');
        tyTDateTime   = hex2dec('21000008');
        tyFloat8Array = hex2dec('2001FFFF');
        tyAnsiString  = hex2dec('4001FFFF');
        tyWideString  = hex2dec('4002FFFF');
        tyBinaryBlob  = hex2dec('FFFFFFFF');
        % RecordTypes
        rtPicoHarpT2Cust = hex2dec('00010202');% (SubID = $00 ,RecFmt: $01) (V1), T-Mode: $02 (T2), HW: $03 (PicoHarp Custom from Labview)
        rtPicoHarpT3     = hex2dec('00010303');% (SubID = $00 ,RecFmt: $01) (V1), T-Mode: $03 (T3), HW: $03 (PicoHarp)
        rtPicoHarpT2     = hex2dec('00010203');% (SubID = $00 ,RecFmt: $01) (V1), T-Mode: $02 (T2), HW: $03 (PicoHarp)
        rtHydraHarpT3    = hex2dec('00010304');% (SubID = $00 ,RecFmt: $01) (V1), T-Mode: $03 (T3), HW: $04 (HydraHarp)
        rtHydraHarpT2    = hex2dec('00010204');% (SubID = $00 ,RecFmt: $01) (V1), T-Mode: $02 (T2), HW: $04 (HydraHarp)
        rtHydraHarp2T3   = hex2dec('01010304');% (SubID = $01 ,RecFmt: $01) (V2), T-Mode: $03 (T3), HW: $04 (HydraHarp)
        rtHydraHarp2T2   = hex2dec('01010204');% (SubID = $01 ,RecFmt: $01) (V2), T-Mode: $02 (T2), HW: $04 (HydraHarp)
        rtTimeHarp260NT3 = hex2dec('00010305');% (SubID = $00 ,RecFmt: $01) (V1), T-Mode: $03 (T3), HW: $05 (TimeHarp260N)
        rtTimeHarp260NT2 = hex2dec('00010205');% (SubID = $00 ,RecFmt: $01) (V1), T-Mode: $02 (T2), HW: $05 (TimeHarp260N)
        rtTimeHarp260PT3 = hex2dec('00010306');% (SubID = $00 ,RecFmt: $01) (V1), T-Mode: $03 (T3), HW: $06 (TimeHarp260P)
        rtTimeHarp260PT2 = hex2dec('00010206');% (SubID = $00 ,RecFmt: $01) (V1), T-Mode: $02 (T2), HW: $06 (TimeHarp260P)
        rtMultiHarpNT3   = hex2dec('00010307');% (SubID = $00 ,RecFmt: $01) (V1), T-Mode: $03 (T3), HW: $07 (MultiHarp150N)
        rtMultiHarpNT2   = hex2dec('00010207');% (SubID = $00 ,RecFmt: $01) (V1), T-Mode: $02 (T2), HW: $07 (MultiHarp150N)
        
        TTResultFormat_TTTRRecType = 0;
        TTResult_NumberOfRecords = 0;
        time_Resolution = 0;
        time_GlobalResolution = 0;
        SyncRate = 0;
        Period = 0;
        
        isT2 = 0;
        isT3 = 0;
        isTimeTrace = 0;
        isPLdecay = 0;
        isG2 = 0;
        
        txtout=cell(5,1);
        
        cnt_ph01;
        cnt_ph01_m;
        cnt_ph0;
        cnt_ph1;
        cnt_ov;
        cnt_ma;
        
        output_headers;
        output_mark_code;
        output_mark_sync;
        output_mark_chan;
        output_mark_dtime;
        
        output_ph_chan;
        output_ph_chan_m;
        
        output_ph_sync;
        output_ph_sync_m;
        
        output_ph_dtime;
        output_ph_dtime_m; %time/threshold masked time arrivals for T3
        
        output_ph_ch01;
        output_ph_ch0;
        output_ph_ch1;
        
        output_ph_ch01_m; %time/threshold masked time arrivals for T2
        output_ph_ch0_m; %time/threshold masked time arrivals for T2
        output_ph_ch1_m; %time/threshold masked time arrivals for T2
        
        mask_time;
        mask_threshold;
        
        data_timetrace; %channel 1  /per bin
        data_timetrace_Time; %in seconds
        data_timetrace0; %channel (only for T2)   /per bin
        data_timetrace0_Time; %in seconds
        data_timetrace01; %channel (only for T2)   /per bin
        data_timetrace01_Time; %in seconds
        
        data_PLdecay; %/per bin
        data_PLdecay_Time; %in nanoseconds
        
        data_g2; %per bin
        data_g2_Time; %in picoseconds
        data_g2raw; %per bin
        data_g2raw_Time;
        
    end
    
    methods (Access = private)
        
        function ReadPT2(app,fid)
            
            T2WRAPAROUND=210698240;
            
            T2Record = fread(fid, inf, 'uint32=>uint32', 'ieee-le');
            
            T2time = mod(T2Record,2^28); % last 28 bits
            %   +-------------------------------+  +-------------------------------+
            %   | | | | |x|x|x|x|x|x|x|x|x|x|x|x|  |x|x|x|x|x|x|x|x|x|x|x|x|x|x|x|x|
            chan=mod(idivide(T2Record,2^28),2^4); % next 4 bits
            %   +-------------------------------+  +-------------------------------+
            %   |x|x|x|x| | | | | | | | | | | | |  | | | | | | | | | | | | | | | | |
            %   +-------------------------------+  +-------------------------------+
            markers = mod(T2Record,2^4);  % where the lowest 4 bits are marker bits
            %   +-------------------------------+  +-------------------------------+
            %   | | | | | | | | | | | | | | | | |  | | | | | | | | | | | | |x|x|x|x|
            %   +-------------------------------+  +-------------------------------+
            
            
            % calculate the overflow:
            Overflow = T2WRAPAROUND * 1 .*uint32(chan==15 & markers == 0);
            
            % create vector of the actual overflow for each entry:
            OverflowCorrection = cumsum(double(Overflow));
            
            % how many overflows in the file:
            app.cnt_ov = max(OverflowCorrection)/T2WRAPAROUND;
            
            % channel of photons
            app.output_ph_chan = chan(chan >= 0 & chan <=4);
            
            % timetag/arrival bin of photons
            app.output_ph_dtime = OverflowCorrection(chan >= 0 & chan <=4) + double(T2time(chan >= 0 & chan <=4)); %sum from all channels
            app.output_ph_ch0 = OverflowCorrection(chan == 0) + double(T2time(chan == 0)); % only for zero/sync channel from picoharp
            app.output_ph_ch1 = OverflowCorrection(chan == 1) + double(T2time(chan == 1)); % only from first/photon channel from picoharp


            % calculate the time of markers
            
            app.output_mark_dtime = OverflowCorrection(chan == 15 & ~(markers == 0)) + double( T2time(chan == 15 & ~(markers == 0)) );
            app.output_mark_code = markers(chan == 15 & ~(markers == 0));
            
            app.cnt_ma=length(app.output_mark_dtime);
            app.cnt_ph01 = length(app.output_ph_dtime);
            app.cnt_ph0 = length(T2time(chan == 0));
            app.cnt_ph1 = length(T2time(chan == 1));
            
            app.Ch0Ch1CountsEditField.Value = app.cnt_ph01;
            app.Ch0CountsEditField.Value = app.cnt_ph0;
            app.Ch1CountsEditField.Value = app.cnt_ph1;
            app.OverflowCountsEditField.Value = app.cnt_ov;
            app.MarkerCountsEditField.Value = app.cnt_ma;
            
            %             assignin('base','data_ch0',app.output_ph_ch0);
            %             assignin('base','data_ch1',app.output_ph_ch1);
            %             assignin('base','channels',app.output_ph_chan);
            
            
        end
        
        function ReadPT3(app,fid)
            
            T3WRAPAROUND=2^16;
            
            
            T3Record = fread(fid, inf, 'uint32=>uint32', 'ieee-le');     % all 32 bits:
            %   +-------------------------------+  +-------------------------------+
            %   |x|x|x|x|x|x|x|x|x|x|x|x|x|x|x|x|  |x|x|x|x|x|x|x|x|x|x|x|x|x|x|x|x|
            %   +-------------------------------+  +-------------------------------+
            
            
            nsync = mod(T3Record,2^16); % last 16 bits
            % this is 2 things:
            %   +-------------------------------+  +-------------------------------+
            %   | | | | | | | | | | | | | | | | |  |x|x|x|x|x|x|x|x|x|x|x|x|x|x|x|x|
            
            chan=mod(idivide(T3Record,2^28),2^4); % the upper 4 bits
            %   +-------------------------------+  +-------------------------------+
            %   |x|x|x|x| | | | | | | | | | | | |  | | | | | | | | | | | | | | | | |
            %   +-------------------------------+  +-------------------------------+
            markers=mod(idivide(T3Record,2^16),2^4); % next 4 bits
            %   +-------------------------------+  +-------------------------------+
            %   | | | | | | | | | | | | |x|x|x|x|  | | | | | | | | | | | | | | | | |
            %   +-------------------------------+  +-------------------------------+
            
            dtime=mod(idivide(T3Record,2^16),2^12); % next 12 bits
            %   +-------------------------------+  +-------------------------------+
            %   | | | | |x|x|x|x|x|x|x|x|x|x|x|x|  | | | | | | | | | | | | | | | | |
            %   +-------------------------------+  +-------------------------------+
            
            
            nsync2=nsync; % make a copy since we need the original nsync later.
            
            % Change all the 0 in nsync2 but only when there is an
            % overflow:
            nsync2(chan==15 & markers == 0 & ~nsync)=1;
            
            % calculate the overflow:
            Overflow = T3WRAPAROUND * nsync2 .*uint32(chan==15 & markers == 0);
            
            % create vector of the actual overflow for each entry:
            OverflowCorrection = cumsum(double(Overflow));
            
            % how many overflows in the file:
            app.cnt_ov = max(OverflowCorrection)/T3WRAPAROUND;
            
            % calculate the actual sync of photons (special=0)
            % one nsync time unit equals to "syncperiod" which can be
            % calculated from "SyncRate"
            app.output_ph_sync = OverflowCorrection(chan >= 1 & chan <=4) + double( nsync(chan >= 1 & chan <=4));
            
            % arrival bin of photons after sync
            app.output_ph_dtime = dtime(chan >= 1 & chan <=4);
            
            % channel of photons
            app.output_ph_chan = chan(chan >= 1 & chan <=4);
            
            % calculate the actual sync of markers
            % Markers: Bitfield of arrived Markers, different markers can arrive at same time (same record)
            app.output_mark_sync = OverflowCorrection(chan == 15 & ~(markers == 0)) + double( nsync(chan == 15 & ~(markers == 0)) );
            app.output_mark_code = markers(chan == 15 & ~(markers == 0));
            
            app.cnt_ma=length(app.output_mark_sync);
            app.cnt_ph0 = length(app.output_ph_sync);
            app.cnt_ph1 = length(app.output_ph_dtime);
            
            app.Ch0CountsEditField.Value = app.cnt_ph0;
            app.Ch1CountsEditField.Value = app.cnt_ph1;
            app.OverflowCountsEditField.Value = app.cnt_ov;
            app.MarkerCountsEditField.Value = app.cnt_ma;
            
        end
        
        function ReadHT2(app,fid,Version) %TO BE REWRITTEN
            
            OverflowCorrection = 0;
            T2WRAPAROUND_V1=33552000;
            T2WRAPAROUND_V2=33554432; % = 2^25  IMPORTANT! THIS IS NEW IN FORMAT V2.0
            
            T2Record = fread(fid, inf, 'uint32=>uint32', 'ieee-le');
            %   +-------------------------------+  +-------------------------------+
            %   |x|x|x|x|x|x|x|x|x|x|x|x|x|x|x|x|  |x|x|x|x|x|x|x|x|x|x|x|x|x|x|x|x|
            %   +-------------------------------+  +-------------------------------+
            nsync = mod(T2Record,2^10); % last 10 bits
            % this is 2 things:
            % a: for an overflow it's how many overflows since last
            % photon/marker
            % b: for photon/marker it's how many syncs since last overflow
            %   +-------------------------------+  +-------------------------------+
            %   | | | | | | | | | | | | | | | | |  | | | | | | |x|x|x|x|x|x|x|x|x|x|
            %   +-------------------------------+  +-------------------------------+
            dtime=mod(idivide(T2Record,2^10),2^15); % next 15 bits
            %   the dtime unit depends on "Resolution" that can be obtained from header
            %   DTime: Arrival time (units) of Photon after last Sync event
            %   DTime * Resolution = Real time arrival of Photon after last Sync event
            %   +-------------------------------+  +-------------------------------+
            %   | | | | | | | |x|x|x|x|x|x|x|x|x|  |x|x|x|x|x|x| | | | | | | | | | |
            %   +-------------------------------+  +-------------------------------+
            chan=mod(idivide(T2Record,2^25),2^6); % next 6 bits
            %   +-------------------------------+  +-------------------------------+
            %   | |x|x|x|x|x|x| | | | | | | | | |  | | | | | | | | | | | | | | | | |
            %   +-------------------------------+  +-------------------------------+
            
            special=idivide(T2Record,2^31); % first bit
            %   +-------------------------------+  +-------------------------------+
            %   |x| | | | | | | | | | | | | | | |  | | | | | | | | | | | | | | | | |
            %   +-------------------------------+  +-------------------------------+
            
            if Version == 1
                T2WRAPAROUND=T2WRAPAROUND_V1;% empty for now, may be adapted for old files
            else
                T2WRAPAROUND=T2WRAPAROUND_V2;
            end
            
            % overflow is known by channel 63
            % for some reason nsync might be 0 even for an overflow (due to
            % old versions or something). in these cases the overflow is
            % eaual to 1.
            
            nsync2=nsync; % make a copy since we need the original nsync later.
            
            % Change all the 0 in nsync2 but only when there is an
            % overflow:
            nsync2(chan==63 & ~nsync)=1;
            
            % calculate the actual overflow:
            Overflow = T2WRAPAROUND * nsync2 .*uint32(chan==63);
            
            % create vector of the actual overflow for each entry:
            OverflowCorrection = cumsum(double(Overflow));
            
            % how many overflows in the file:
            app.cnt_ov = max(OverflowCorrection)/T2WRAPAROUND;
            
            % calculate the actual sync of photons (special=0)
            % one nsync time unit equals to "syncperiod" which can be
            % calculated from "SyncRate"
            app.output_ph_sync = OverflowCorrection(special==0) + double( nsync(special==0) );
            
            % arrival bin of photons after sync
            app.output_ph_dtime = dtime(special==0);
            
            % channel of photons
            app.output_ph_chan = channel(special==0);
            
            app.cnt_ph1 = length(app.output_ph_dtime);
            
            % calculate the actual sync of markers
            % Markers: Bitfield of arrived Markers, different markers can arrive at same time (same record)
            app.output_mark_sync = OverflowCorrection(special==1 & chan>=1 & chan<=15) + double( nsync(special==1 & chan>=1 & chan<=15) );
            app.output_mark_chan = channel(special==1 & ~(chan==63));
            app.output_mark_dtime = dtime(special==1 & ~(chan==63));
            app.cnt_ma=length(app.output_mark_chan);
            
            app.Ch0CountsEditField.Value = app.cnt_ph0;
            app.Ch1CountsEditField.Value = app.cnt_ph1;
            app.OverflowCountsEditField.Value = app.cnt_ov;
            app.MarkerCountsEditField = app.cnt_ma;
            
        end
        function ReadHT3(app,fid,Version)
            T3WRAPAROUND = 2^10;
            T3Record = fread(fid, inf, 'uint32=>uint32', 'ieee-le');
            %   +-------------------------------+  +-------------------------------+
            %   |x|x|x|x|x|x|x|x|x|x|x|x|x|x|x|x|  |x|x|x|x|x|x|x|x|x|x|x|x|x|x|x|x|
            %   +-------------------------------+  +-------------------------------+
            nsync = mod(T3Record,2^10); % last 10 bits
            % this is 2 things:
            % a: for an overflow it's how many overflows since last
            % photon/marker
            % b: for photon/marker it's how many syncs since last overflow
            %   +-------------------------------+  +-------------------------------+
            %   | | | | | | | | | | | | | | | | |  | | | | | | |x|x|x|x|x|x|x|x|x|x|
            %   +-------------------------------+  +-------------------------------+
            dtime=mod(idivide(T3Record,2^10),2^15); % next 15 bits
            %   the dtime unit depends on "Resolution" that can be obtained from header
            %   DTime: Arrival time (units) of Photon after last Sync event
            %   DTime * Resolution = Real time arrival of Photon after last Sync event
            %   +-------------------------------+  +-------------------------------+
            %   | | | | | | | |x|x|x|x|x|x|x|x|x|  |x|x|x|x|x|x| | | | | | | | | | |
            %   +-------------------------------+  +-------------------------------+
            chan=mod(idivide(T3Record,2^25),2^6); % next 6 bits
            %   +-------------------------------+  +-------------------------------+
            %   | |x|x|x|x|x|x| | | | | | | | | |  | | | | | | | | | | | | | | | | |
            %   +-------------------------------+  +-------------------------------+
            
            special=idivide(T3Record,2^31); % first bit
            %   +-------------------------------+  +-------------------------------+
            %   |x| | | | | | | | | | | | | | | |  | | | | | | | | | | | | | | | | |
            %   +-------------------------------+  +-------------------------------+
            
            if Version == 1
                % empty for now, may be adapted for old files
                
            else
                % overflow is known by channel 63
                % for some reason nsync might be 0 even for an overflow (due to
                % old versions or something). in these cases the overflow is
                % eaual to 1.
                
                nsync2=nsync; % make a copy since we need the original nsync later.
                
                % Change all the 0 in nsync2 but only when there is an
                % overflow:
                nsync2(chan==63 & ~nsync)=1;
                
                % calculate the actual overflow:
                Overflow = T3WRAPAROUND * nsync2 .*uint32(chan==63);
                
                % create vector of the actual overflow for each entry:
                OverflowCorrection = cumsum(double(Overflow));
                
                % how many overflows in the file:
                app.cnt_ov = max(OverflowCorrection)/T3WRAPAROUND;
            end
            
            % calculate the actual sync of photons (special=0)
            % one nsync time unit equals to "syncperiod" which can be
            % calculated from "SyncRate"
            app.output_ph_sync = OverflowCorrection(special==0) + double( nsync(special==0) );
            
            % arrival bin of photons after sync
            app.output_ph_dtime = dtime(special==0);
            
            % channel of photons
            app.output_ph_chan = channel(special==0);
            
            % calculate the actual sync of markers
            % Markers: Bitfield of arrived Markers, different markers can arrive at same time (same record)
            app.output_mark_sync = OverflowCorrection(special==1 & chan>=1 & chan<=15) + double( nsync(special==1 & chan>=1 & chan<=15) );
            app.output_mark_chan = channel(special==1 & ~(chan==63));
            app.output_mark_dtime = dtime(special==1 & ~(chan==63));
            
            app.cnt_ma=length(app.output_mark_chan);
            app.cnt_ph0 = length(app.output_ph_sync);
            app.cnt_ph1 = length(app.output_ph_dtime);
            
            app.Ch0CountsEditField.Value = app.cnt_ph0;
            app.Ch1CountsEditField.Value = app.cnt_ph1;
            app.OverflowCountsEditField.Value = app.cnt_ov;
            app.MarkerCountsEditField = app.cnt_ma;
        end
        
        function applyTimeMask(app,t1,t2)
            
            if app.isT2
                app.output_ph_chan_m = app.output_ph_chan(t1<=app.time_Resolution*app.output_ph_dtime & t2>=app.time_Resolution*app.output_ph_dtime);
                app.output_ph_dtime_m = app.output_ph_dtime(t1<=app.time_Resolution*app.output_ph_dtime & t2>=app.time_Resolution*app.output_ph_dtime);
                app.output_ph_ch01_m =[double(app.output_ph_chan_m) app.output_ph_dtime_m];
                app.cnt_ph01_m = length(app.output_ph_dtime_m);
                app.output_ph_ch0_m = app.output_ph_ch0(t1<=app.time_Resolution*app.output_ph_ch0 & t2>=app.time_Resolution*app.output_ph_ch0);
                app.output_ph_ch1_m = app.output_ph_ch1(t1<=app.time_Resolution*app.output_ph_ch1 & t2>=app.time_Resolution*app.output_ph_ch1);
            end
            if app.isT3
                app.output_ph_sync_m = app.output_ph_sync(t1<=(app.time_Resolution*app.output_ph_dtime+app.Period*app.output_ph_sync) & t2>=(app.time_Resolution*app.output_ph_dtime+app.Period*app.output_ph_sync));
                app.output_ph_dtime_m = app.output_ph_dtime(t1<=(app.time_Resolution*app.output_ph_dtime+app.Period*app.output_ph_sync) & t2>=(app.time_Resolution*app.output_ph_dtime+app.Period*app.output_ph_sync));
            end
            
        end
        
        
        function applyThresholdMask(app,tmin,tmax,dt)
            
            if app.isT2
                
                app.output_ph_chan_m = [];
                app.output_ph_dtime_m = [];
                app.output_ph_ch01_m =[];
                app.cnt_ph01_m = [];
                app.output_ph_ch0_m = [];
                app.output_ph_ch1_m = [];
                
                [hValues01,BinEdges01] = histcounts((app.time_Resolution*app.output_ph_ch01(:,2)), 'BinWidth',1);
                [hValues0,BinEdges0] = histcounts((app.time_Resolution*app.output_ph_ch0), 'BinWidth',1);
                [hValues1,BinEdges1] = histcounts((app.time_Resolution*app.output_ph_ch1), 'BinWidth',1);
                
                hTime0 = BinEdges0(2:length(BinEdges0))-0.5; % convert to bin centers
                hTime1 = BinEdges1(2:length(BinEdges1))-0.5;
                hTime01 = BinEdges01(2:length(BinEdges01))-0.5;
                
                hTime0_m = hTime0((hValues0 <=tmax)& (hValues0 >=tmin)); %raw min/max threshold masks
                hTime1_m = hTime1((hValues1 <=tmax)& (hValues1 >=tmin));
                hTime01_m = hTime01((hValues01 <=tmax)& (hValues01 >=tmin));
                
                %apply/add step threshold masks
                
                
                %------------------------------------------------
                
                
                assignin('base','hTime01_m',hTime01_m);
                assignin('base','hTime0_m',hTime0_m);
                assignin('base','hTime1_m',hTime1_m);
                
                % find edges, i.e. start and end times of segments
                
                hTime01_edges = [];
                hTime0_edges = [];
                hTime1_edges = [];
                
                Ni01 = length(hTime01_m);
                Ni0 = length(hTime0_m);
                Ni1 = length(hTime1_m);
                j01 = 0;
                
                if ~isempty(hTime01_m) & Ni01>1
                    for i = 1:Ni01
                        if i==1
                            j01 = j01+1;
                            hTime01_edges(j01,1) = hTime01_m(1);
                        else
                            if (hTime01_m(i)-hTime01_m(i-1)) > 1
                                j01 = j01+1;
                                hTime01_edges(j01,1) = hTime01_m(i);
                                hTime01_edges(j01-1,2) = hTime01_m(i-1);
                                
                            end
                        end
                        if i==Ni01
                            hTime01_edges(j01,2) = hTime01_m(Ni01);
                        end
                    end
                    
                    % extend the limit by one bin that is 1 sec (+1- left and right)= the first and the last seconds will be lost,
                    % givent that 0.5s is already cut by bin center to edge difference
                    hTime01_edges(:,1) = hTime01_edges(:,1)+0.5;
                    hTime01_edges(:,2) = hTime01_edges(:,2)-0.5;
                    
                    % (1) remove "zero-second" segment
                    hTime01_edges(hTime01_edges(:,1) >= hTime01_edges(:,2),:) = [] ;
                end
                
                for i = 1:length(hTime01_edges(:,1))
                    
                    t1 = hTime01_edges(i,1);
                    t2 = hTime01_edges(i,2);
                    
                    app.output_ph_chan_m = vertcat(app.output_ph_chan_m,app.output_ph_chan(t1<=app.time_Resolution*app.output_ph_dtime & t2>=app.time_Resolution*app.output_ph_dtime));
                    app.output_ph_dtime_m = vertcat(app.output_ph_dtime_m, app.output_ph_dtime(t1<=app.time_Resolution*app.output_ph_dtime & t2>=app.time_Resolution*app.output_ph_dtime));
                    
                    app.output_ph_ch0_m = vertcat(app.output_ph_ch0_m,app.output_ph_ch0(t1<=app.time_Resolution*app.output_ph_ch0 & t2>=app.time_Resolution*app.output_ph_ch0));
                    app.output_ph_ch1_m = vertcat(app.output_ph_ch1_m, app.output_ph_ch1(t1<=app.time_Resolution*app.output_ph_ch1 & t2>=app.time_Resolution*app.output_ph_ch1));
                end
                app.output_ph_ch01_m =[double(app.output_ph_chan_m) app.output_ph_dtime_m];
                app.cnt_ph01_m = length(app.output_ph_dtime_m);
            end
            if app.isT3
                
                app.output_ph_sync_m = [];
                app.output_ph_dtime_m = [];
                
                [hValues,BinEdges] = histcounts((app.time_Resolution*app.output_ph_dtime+app.Period*app.output_ph_sync), 'BinWidth',1);
                
                hTime = BinEdges(2:length(BinEdges))-0.5; % convert to bin centers
                hTime_m = hTime((hValues <=tmax)& (hValues >=tmin)); %raw min/max threshold masks
                
                %apply/add step threshold masks
                
                %------------------------------------------------
                
                % find edges, i.e. start and end times of segments
                
                hTime_edges = [];
                
                Ni = length(hTime_m);
                j = 0;
                
                if ~isempty(hTime_m) & Ni>1
                    for i = 1:Ni
                        if i==1
                            j = j+1;
                            hTime_edges(j,1) = hTime_m(1);
                        else
                            if (hTime_m(i)-hTime_m(i-1)) > 1
                                j = j+1;
                                hTime_edges(j,1) = hTime_m(i);
                                hTime_edges(j-1,2) = hTime_m(i-1);
                                
                            end
                        end
                        if i==Ni
                            hTime_edges(j,2) = hTime_m(Ni);
                        end
                    end
                    
                    % extend the limit by one bin that is 1 sec (+1- left and right)= the first and the last seconds will be lost,
                    % givent that 0.5s is already cut by bin center to edge difference
                    hTime_edges(:,1) = hTime_edges(:,1)+0.5;
                    hTime_edges(:,2) = hTime_edges(:,2)-0.5;
                    
                    % (1) remove "zero-second" segment
                    hTime_edges(hTime_edges(:,1) >= hTime_edges(:,2),:) = [] ;
                end
                
                for i = 1:length(hTime_edges(:,1))
                    
                    t1 = hTime_edges(i,1);
                    t2 = hTime_edges(i,2);
                
                    app.output_ph_sync_m = vertcat(app.output_ph_sync_m, app.output_ph_sync(t1<=(app.time_Resolution*app.output_ph_dtime+app.Period*app.output_ph_sync) & t2>=(app.time_Resolution*app.output_ph_dtime+app.Period*app.output_ph_sync)));
                    app.output_ph_dtime_m = vertcat(app.output_ph_dtime_m, app.output_ph_dtime(t1<=(app.time_Resolution*app.output_ph_dtime+app.Period*app.output_ph_sync) & t2>=(app.time_Resolution*app.output_ph_dtime+app.Period*app.output_ph_sync)));
                end
            end
            
        end
    end
    

    methods (Access = private)

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            app.FolderEditField.Value = uigetdir (app.DefaultFolder);
        end

        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            %             if app.UseLocalFolderCheckBox.Value
            %                 app.FolderEditField.Value=app.DefaultFolder;
            %             end
            
            if app.CustomFileCheckBox.Value
                app.FileEditField.Value= uigetfile( {'*.ptc',  'Custom PicoQuant file (*.ptc)';'*.ptu',  'PicoQuant Universal file (*.ptu)';'*.*',  'All files (*.*)'},'Select a file',app.FolderEditField.Value,'MultiSelect', 'off');
            else
                app.FileEditField.Value= uigetfile( {'*.ptu',  'PicoQuant Universal file (*.ptu)';'*.ptc',  'Custom PicoQuant file (*.ptc)';'*.*',  'All files (*.*)'},'Select a file',app.FolderEditField.Value,'MultiSelect', 'off');
            end
            
            fid=fopen(strcat(app.FolderEditField.Value,'\', app.FileEditField.Value));
            
            app.output_headers = cell.empty(0,0);
            
            if ~app.CustomFileCheckBox.Value
            
                Magic = fread(fid, 8, '*char');
                if not(strcmp(Magic(Magic~=0)','PQTTTR'))
                    error('Magic invalid, this is not an PTU file.');
                end
                Version = fread(fid, 8, '*char');
                app.txtout{1}=sprintf('Tag Version: %s', Version);
                j=0;
                while 1 % read all headears
                    j=j+1;
                    % read Tag Head
                    TagIdent = fread(fid, 32, '*char');    % TagHead.Ident
                    TagIdent = (TagIdent(TagIdent ~= 0))'; % remove #0 and more more readable
                    TagIdx = fread(fid, 1, 'int32');       % TagHead.Idx
                    TagTyp = fread(fid, 1, 'uint32');      % TagHead.Typ
                    % TagHead.Value will be read in the
                    % right type function
                    if TagIdx > -1
                        EvalName = [TagIdent '(' int2str(TagIdx + 1) ')'];
                    else
                        EvalName = TagIdent;
                    end
                    app.output_headers{j,1}=EvalName;
                    % check Typ of Header
                    switch TagTyp
                        case app.tyEmpty8
                            fread(fid, 1, 'int64');
                            app.output_headers{j,2}='<Empty>';
                        case app.tyBool8
                            TagInt = fread(fid, 1, 'int64');
                            if TagInt==0
                                app.output_headers{j,2}='FALSE';
                                eval([EvalName '=false;']);
                            else
                                app.output_headers{j,2}='TRUE';
                                eval([EvalName '=true;']);
                            end
                        case app.tyInt8
                            TagInt = fread(fid, 1, 'int64');
                            app.output_headers{j,2}=TagInt;
                            eval([EvalName '=TagInt;']);
                        case app.tyBitSet64
                            TagInt = fread(fid, 1, 'int64');
                            app.output_headers{j,2}=TagInt;
                            eval([EvalName '=TagInt;']);
                        case app.tyColor8
                            TagInt = fread(fid, 1, 'int64');
                            app.output_headers{j,2}=TagInt;
                            eval([EvalName '=TagInt;']);
                        case app.tyFloat8
                            TagFloat = fread(fid, 1, 'double');
                            app.output_headers{j,2}=TagFloat;
                            eval([EvalName '=TagFloat;']);
                        case app.tyFloat8Array
                            TagInt = fread(fid, 1, 'int64');
                            app.output_headers{j,2}=['<Float array with %d Entries>', TagInt / 8];
                            fseek(fid, TagInt, 'cof');
                        case app.tyTDateTime
                            TagFloat = fread(fid, 1, 'double');
                            app.output_headers{j,2}=datestr(datenum(1899,12,30)+TagFloat);
                            eval([EvalName '=datenum(1899,12,30)+TagFloat;']); % but keep in memory as Matlab Date Number
                        case app.tyAnsiString
                            TagInt = fread(fid, 1, 'int64');
                            TagString = fread(fid, TagInt, '*char');
                            TagString = (TagString(TagString ~= 0))';
                            app.output_headers{j,2}=TagString;
                            if TagIdx > -1
                                EvalName = [TagIdent '(' int2str(TagIdx + 1) ',:)'];
                            end
                            eval([EvalName '=TagString;']);
                        case app.tyWideString
                            % Matlab does not support Widestrings at all, just read and
                            % remove the 0's (up to current (2012))
                            TagInt = fread(fid, 1, 'int64');
                            TagString = fread(fid, TagInt, '*char');
                            TagString = (TagString(TagString ~= 0))';
                            app.output_headers{j,2}=TagString;
                            if TagIdx > -1
                                EvalName = [TagIdent '(' int2str(TagIdx + 1) ',:)'];
                            end
                            eval([EvalName '=TagString;']);
                        case app.tyBinaryBlob
                            TagInt = fread(fid, 1, 'int64');
                            app.output_headers{j,2}=['<Binary Blob with %d Bytes>', TagInt];
                            fseek(fid, TagInt, 'cof');
                        otherwise
                            error('Illegal Type identifier found! Broken file?');
                    end
                    if strcmp(TagIdent, 'Header_End')
                        break
                    end
                end


            % this takes the important headers (defined at the beginning of the
            % function) and puts them directly in the output structure.
            app.output_headers(:,1) = strrep(app.output_headers(:,1),'(','_');
            app.output_headers(:,1) = strrep(app.output_headers(:,1),')','');
            app.output_headers=cell2struct(app.output_headers(:,2),app.output_headers(:,1),1);
            
            app.TTResultFormat_TTTRRecType = app.output_headers.TTResultFormat_TTTRRecType;
            app.TTResult_NumberOfRecords = app.output_headers.TTResult_NumberOfRecords;
            app.time_Resolution = app.output_headers.MeasDesc_Resolution;
            app.time_GlobalResolution = app.output_headers.MeasDesc_GlobalResolution;
            app.SyncRate = app.output_headers.TTResult_SyncRate;
            app.Period = 1/app.output_headers.TTResult_SyncRate;
            
            app.ResolutionEditField.Value = app.time_Resolution*1e+12;
            app.PeriodEditField.Value = app.Period*1e+9;
            
            else
                app.txtout{1}=sprintf('Tag Version: %s', 'Custom');
                app.TTResultFormat_TTTRRecType = app.rtPicoHarpT2Cust;
                app.TTResult_NumberOfRecords = 1;
                app.time_Resolution = 4*1e-12;
                app.time_GlobalResolution = 4*1e-12;
                app.SyncRate = 1e+7;
                app.Period = 1e-7;
                
                app.ResolutionEditField.Value = app.time_Resolution*1e+12;
                app.PeriodEditField.Value = app.Period*1e+9;
            
            end
            
            % choose right decode function
            switch app.TTResultFormat_TTTRRecType
                case app.rtPicoHarpT2Cust
                    app.txtout{2}=sprintf('PicoHarp T2C');
                    app.DataFormatEditField.Value='PicoHarp T2C';
                    ReadPT2(app,fid);
                    app.isT2 = 1;app.isT3 = 0; 
                case app.rtPicoHarpT2
                    app.txtout{2}=sprintf('PicoHarp T2');
                    app.DataFormatEditField.Value='PicoHarp T2';
                    ReadPT2(app,fid);
                    app.isT2 = 1;app.isT3 = 0;
                case app.rtPicoHarpT3
                    app.txtout{2}=sprintf('PicoHarp T3');
                    app.DataFormatEditField.Value='PicoHarp T3';
                    ReadPT3(app,fid);
                    app.isT3 = 1;app.isT2 = 0;
                case app.rtHydraHarpT2
                    app.txtout{2}=sprintf('HydraHarp V1 T2');
                    app.DataFormatEditField.Value='HydraHarp V1 T2';
                    ReadHT2(app,fid,1);
                    app.isT2 = 1;app.isT3 = 0;
                case app.rtHydraHarp2T2
                    app.txtout{2}=sprintf('HydraHarp V2 T2');
                    app.DataFormatEditField.Value='HydraHarp V2 T2';
                    ReadHT2(app,fid,2);
                    app.isT2 = 1;app.isT3 = 0;
                case app.rtHydraHarpT3
                    app.txtout{2}=sprintf('HydraHarp V1 T3');
                    app.DataFormatEditField.Value='HydraHarp V1 T3';
                    ReadHT3(app,fid,1);
                    app.isT3 = 1;app.isT2 = 0;
                case app.rtHydraHarp2T3
                    app.txtout{2}=sprintf('HydraHarp V2 T3 data');
                    app.DataFormatEditField.Value='HydraHarp V2 T3 data';
                    ReadHT3(app,fid,2);
                    app.isT3 = 1;app.isT2 = 0;
                otherwise
                    app.DataFormatEditField.Value='Illegal RecordType!';
            end
            fclose(fid);
            
            %convert uint32 to double
            app.output_ph_sync=double(app.output_ph_sync);
            app.output_ph_dtime=double(app.output_ph_dtime);
            app.output_ph_ch01=[double(app.output_ph_chan) app.output_ph_dtime];
            app.output_ph_ch0=double(app.output_ph_ch0);
            app.output_ph_ch1=double(app.output_ph_ch1);
            
            app.output_mark_dtime=double(app.output_mark_dtime);
            app.output_mark_sync=double(app.output_mark_sync);
            
            
            app.txtout{3}=sprintf('Statistics obtained from the data:');
            app.txtout{4}=sprintf('%i photons Ch0,%i photons Ch1, %i overflows, %i markers.',app.cnt_ph0, app.cnt_ph1, app.cnt_ov, app.cnt_ma);


        end

        % Button pushed function: PlotPLdecayButton
        function PlotPLdecayButtonPushed(app, event)
            if app.isT3
                if app.UseTimeMaskCheckBox.Value || app.UseThresholdCheckBox.Value
                    [Values,BinEdges] = histcounts(app.time_Resolution*app.output_ph_dtime_m*1e+9, 'BinWidth',app.PLdecayBinWidthEditField.Value);
                else
                    [Values,BinEdges] = histcounts(app.time_Resolution*app.output_ph_dtime*1e+9, 'BinWidth',app.PLdecayBinWidthEditField.Value);
                end
                
                app.data_PLdecay = Values;
                app.data_PLdecay_Time = BinEdges(2:length(BinEdges));
                
                plot(app.gDecay,app.data_PLdecay_Time,Values);
                
                app.isPLdecay = 1;
            end
        end

        % Button pushed function: PlotGlobalTimeTraceButton
        function PlotGlobalTimeTraceButtonPushed(app, event)
            if app.isT3
                if app.UseTimeMaskCheckBox.Value || app.UseThresholdCheckBox.Value
                    [Values,BinEdges] = histcounts((app.time_Resolution*app.output_ph_dtime_m+app.Period*app.output_ph_sync_m), 'BinWidth',app.TimeTraceBinWidthEditField.Value);
                else
                    [Values,BinEdges] = histcounts((app.time_Resolution*app.output_ph_dtime+app.Period*app.output_ph_sync), 'BinWidth',app.TimeTraceBinWidthEditField.Value);
                end
                
                app.data_timetrace = Values;
                app.data_timetrace_Time = BinEdges(2:length(BinEdges));
                
                app.StartTimeEditField.Value = min(BinEdges);
                app.EndTimeEditField.Value = max(BinEdges);
                app.ThresholdMinCREditField = min(Values)/app.TimeTraceBinWidthEditField.Value;
                app.ThresholdMaxCREditField = max(Values)/app.TimeTraceBinWidthEditField.Value;
                
                plot(app.gGlobalTimeTrace,app.data_timetrace_Time,Values/app.TimeTraceBinWidthEditField.Value);
                
                app.isTimeTrace = 1;
            end
            if app.isT2
                if app.UseTimeMaskCheckBox.Value || app.UseThresholdCheckBox.Value
                    [Values01,BinEdges01] = histcounts((app.time_Resolution*app.output_ph_ch01_m(:,2)), 'BinWidth',app.TimeTraceBinWidthEditField.Value);
                    [Values0,BinEdges0] = histcounts((app.time_Resolution*app.output_ph_ch0_m), 'BinWidth',app.TimeTraceBinWidthEditField.Value);
                    [Values1,BinEdges1] = histcounts((app.time_Resolution*app.output_ph_ch1_m), 'BinWidth',app.TimeTraceBinWidthEditField.Value);
                else
                    [Values01,BinEdges01] = histcounts((app.time_Resolution*app.output_ph_ch01(:,2)), 'BinWidth',app.TimeTraceBinWidthEditField.Value);
                    [Values0,BinEdges0] = histcounts((app.time_Resolution*app.output_ph_ch0), 'BinWidth',app.TimeTraceBinWidthEditField.Value);
                    [Values1,BinEdges1] = histcounts((app.time_Resolution*app.output_ph_ch1), 'BinWidth',app.TimeTraceBinWidthEditField.Value);
                end
                
                app.data_timetrace01 = Values01;
                app.data_timetrace01_Time = BinEdges01(2:length(BinEdges01));
                app.data_timetrace0 = Values0;
                app.data_timetrace0_Time = BinEdges0(2:length(BinEdges0));
                app.data_timetrace = Values1;
                app.data_timetrace_Time = BinEdges1(2:length(BinEdges1));
                
                app.StartTimeEditField.Value = min(BinEdges1);
                app.EndTimeEditField.Value = max(BinEdges1);
                
                app.ThresholdMinCREditField.Value = min(Values01)/app.TimeTraceBinWidthEditField.Value;
                app.ThresholdMaxCREditField.Value = max(Values01)/app.TimeTraceBinWidthEditField.Value;
                
                plot(app.gGlobalTimeTrace,...
                    app.data_timetrace0_Time,Values0/app.TimeTraceBinWidthEditField.Value,...
                    app.data_timetrace_Time,Values1/app.TimeTraceBinWidthEditField.Value,...
                    app.data_timetrace01_Time,Values01/app.TimeTraceBinWidthEditField.Value);
                
                app.isTimeTrace = 1;
                
                %                 assignin('base','Values01',app.output_ph_ch01_m);
                %                 assignin('base','Values0',app.output_ph_ch0_m);
                %                 assignin('base','Values1',Values1);
                
            end
            
        end

        % Button pushed function: PlotG2Button
        function PlotG2ButtonPushed(app, event)
            if app.isT2
                timeLag = app.MaxTimeLagEditField.Value * 1e-9/app.time_GlobalResolution;
                g2_raw = double.empty(0,0);
                count_ind = 0;
                
                if app.UseTimeMaskCheckBox.Value || app.UseThresholdCheckBox.Value
                    ch01 = app.output_ph_ch01_m;
                    cnt_ph01 = app.cnt_ph01_m;
                else
                    ch01 = app.output_ph_ch01;
                    cnt_ph01 = app.cnt_ph01;
                end
                
                for i = 1:cnt_ph01
                    
                    if ch01(i,1)==0
                        
                        ind = i;
                        %                         tic
                        
                        while ind >= 1
                            if ch01(ind,1)==1
                                delta = ch01(i,2)-ch01(ind,2);
                                if abs(delta) <= timeLag
                                    count_ind = count_ind+1;
                                    g2_raw(count_ind) = delta;
                                else
                                    break
                                end
                            end
                            ind = ind-1;
                        end
                        %                         toc
                        ind = i+1;
                        
                        while ind <= cnt_ph01
                            if ch01(ind,1)==1
                                delta = ch01(i,2)-ch01(ind,2);
                                if abs(delta) <= timeLag
                                    count_ind = count_ind+1;
                                    g2_raw(count_ind) = delta;
                                else
                                    break
                                end
                            end
                            ind = ind+1;
                        end
                        %                         toc
                    end
                end
                
                app.CoincidenceCountsEditField.Value = count_ind;
                
                [Values_raw,BinEdges_raw] = histcounts(app.time_Resolution*g2_raw*1e+9, 'BinWidth',app.time_Resolution*1e+9);
                
                app.data_g2raw = Values_raw;
                app.data_g2raw_Time = BinEdges_raw(2:length(BinEdges_raw));
                
                [Values,BinEdges] = histcounts(app.time_Resolution*g2_raw*1e+9, 'BinWidth',app.G2BinWidthEditField.Value);
                
                app.data_g2 = Values;
                app.data_g2_Time = BinEdges(2:length(BinEdges));
                
                plot(app.gG2,app.data_g2_Time,Values);
                
                assignin('base','data_g2_raw',app.data_g2raw);
                assignin('base','data_g2',app.data_g2);
                
                app.isG2 = 1;
            end
            
        end

        % Button pushed function: ExportTimeTraceButton
        function ExportTimeTraceButtonPushed(app, event)
            if app.isTimeTrace
                if app.isT2
                    dlmwrite(strcat(app.FolderEditField.Value,'\',app.ExportFilePrefixEditField.Value,'TimeTrace_',app.FileEditField.Value,'.txt'),horzcat(app.data_timetrace0_Time.',app.data_timetrace0.',app.data_timetrace_Time.',app.data_timetrace.'));
                end
                
                if app.isT3
                    dlmwrite(strcat(app.FolderEditField.Value,'\',app.ExportFilePrefixEditField.Value,'TimeTrace_',app.FileEditField.Value,'.txt'),horzcat(app.data_timetrace_Time.',app.data_timetrace.'));
                end
            end
        end

        % Button pushed function: ExportPLdecayButton
        function ExportPLdecayButtonPushed(app, event)
            if app.isPLdecay
                dlmwrite(strcat(app.FolderEditField.Value,'\',app.ExportFilePrefixEditField.Value,'PLdecay_',app.FileEditField.Value,'.txt'),horzcat(app.data_PLdecay_Time.',app.data_PLdecay.'));
            end
        end

        % Button pushed function: ExportG2Button
        function ExportG2ButtonPushed(app, event)
            if app.isG2
                dlmwrite(strcat(app.FolderEditField.Value,'\',app.ExportFilePrefixEditField.Value,'G2_',app.FileEditField.Value,'.txt'),horzcat(app.data_g2_Time.',app.data_g2.'));
                dlmwrite(strcat(app.FolderEditField.Value,'\',app.ExportFilePrefixEditField.Value,'G2raw_',app.FileEditField.Value,'.txt'),horzcat(app.data_g2raw_Time.',app.data_g2raw.'));
            end
        end

        % Value changed function: UseTimeMaskCheckBox
        function UseTimeMaskCheckBoxValueChanged(app, event)
            value = app.UseTimeMaskCheckBox.Value;
            if value
                applyTimeMask(app,app.StartTimeEditField.Value,app.EndTimeEditField.Value);
                app.UseThresholdCheckBox.Value = 0;
            end
        end

        % Value changed function: UseThresholdCheckBox
        function UseThresholdCheckBoxValueChanged(app, event)
            value = app.UseThresholdCheckBox.Value;
            dt = app.ThresholdStepEditField.Value;
            if value
                app.UseTimeMaskCheckBox.Value = 0;
                applyThresholdMask(app, app.ThresholdMinCREditField.Value, app.ThresholdMaxCREditField.Value, app.ThresholdStepEditField.Value);
                
            end
        end

        % Value changed function: StartTimeEditField
        function StartTimeEditFieldValueChanged(app, event)
            value = app.StartTimeEditField.Value;
            if value > app.EndTimeEditField.Value
                app.StartTimeEditField.Value = app.EndTimeEditField.Value;
            end
                
            if ~app.UseTimeMaskCheckBox.Value
                app.UseTimeMaskCheckBox.Value = 1;
            end
            applyTimeMask(app,value,app.EndTimeEditField.Value);
            app.UseThresholdCheckBox.Value = 0;
        end

        % Value changed function: EndTimeEditField
        function EndTimeEditFieldValueChanged(app, event)
            value = app.EndTimeEditField.Value;
            if value < app.StartTimeEditField.Value
                app.EndTimeEditField.Value = app.StartTimeEditField.Value;
            end
            if ~app.UseTimeMaskCheckBox.Value
                app.UseTimeMaskCheckBox.Value = 1;
            end
            applyTimeMask(app,app.StartTimeEditField.Value,value);
            app.UseThresholdCheckBox.Value = 0;
        end

        % Value changed function: ThresholdStepEditField
        function ThresholdStepEditFieldValueChanged(app, event)
            value = app.ThresholdStepEditField.Value;
            if ~app.UseThresholdCheckBox.Value
                app.UseThresholdCheckBox.Value = 1;
            end
            applyThresholdMask(app, app.ThresholdMinCREditField.Value, app.ThresholdMaxCREditField.Value, app.ThresholdStepEditField.Value);
            app.UseTimeMaskCheckBox.Value = 0;
            
        end

        % Value changed function: ThresholdMinCREditField
        function ThresholdMinCREditFieldValueChanged(app, event)
            value = app.ThresholdMinCREditField.Value;
            
            if value > app.ThresholdMaxCREditField.Value
                app.ThresholdMinCREditField.Value = app.ThresholdMaxCREditField.Value;
            end

            if ~app.UseThresholdCheckBox.Value
                app.UseThresholdCheckBox.Value = 1;
            end
            applyThresholdMask(app, app.ThresholdMinCREditField.Value, app.ThresholdMaxCREditField.Value, app.ThresholdStepEditField.Value);
            app.UseTimeMaskCheckBox.Value = 0;
            
        end

        % Value changed function: ThresholdMaxCREditField
        function ThresholdMaxCREditFieldValueChanged(app, event)
            value = app.ThresholdMaxCREditField.Value;
            
            if value < app.ThresholdMinCREditField.Value
                app.ThresholdMaxCREditField.Value = app.ThresholdMinCREditField.Value;
            end
                        
            if ~app.UseThresholdCheckBox.Value
                app.UseThresholdCheckBox.Value = 1;
            end
            applyThresholdMask(app, app.ThresholdMinCREditField.Value, app.ThresholdMaxCREditField.Value, app.ThresholdStepEditField.Value);
            app.UseTimeMaskCheckBox.Value = 0;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FolderEditFieldLabel = uilabel(app.UIFigure);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.FIleLabel = uilabel(app.UIFigure);
            app.FIleLabel.Text = 'FIle';
            app.FileEditField = uieditfield(app.UIFigure, 'text');
            app.SelectFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select';
            app.UseLocalFolderCheckBox = uicheckbox(app.UIFigure);
            app.UseLocalFolderCheckBox.Text = 'Use Local';
            app.UseLocalFolderCheckBox.Value = true;
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Text = 'Select';
            app.PlotGlobalTimeTraceButton = uibutton(app.UIFigure, 'push');
            app.PlotGlobalTimeTraceButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGlobalTimeTraceButtonPushed, true);
            app.PlotGlobalTimeTraceButton.Text = 'Calculate';
            app.ExportTimeTraceButton = uibutton(app.UIFigure, 'push');
            app.ExportTimeTraceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportTimeTraceButtonPushed, true);
            app.ExportTimeTraceButton.Text = 'Export';
            app.ExportFilePrefixEditField = uieditfield(app.UIFigure, 'text');
            app.ExportFilePrefixEditField.Value = '_';
            app.StartTimeEditFieldLabel = uilabel(app.UIFigure);
            app.StartTimeEditFieldLabel.Text = 'Start Time (s)';
            app.StartTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartTimeEditField.ValueDisplayFormat = '%.1f';
            app.StartTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimeEditFieldValueChanged, true);
            app.EndTimeEditFieldLabel = uilabel(app.UIFigure);
            app.EndTimeEditFieldLabel.Text = 'End Time (s)';
            app.EndTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndTimeEditField.ValueDisplayFormat = '%.1f';
            app.EndTimeEditField.ValueChangedFcn = createCallbackFcn(app, @EndTimeEditFieldValueChanged, true);
            app.gGlobalTimeTrace = uiaxes(app.UIFigure);
            title(app.gGlobalTimeTrace, 'Global Time Trace')
            xlabel(app.gGlobalTimeTrace, 'Time (s)')
            ylabel(app.gGlobalTimeTrace, 'Count rate (cps)')
            app.ResolutionEditFieldLabel = uilabel(app.UIFigure);
            app.ResolutionEditFieldLabel.Text = 'Resolution (ps)';
            app.ResolutionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResolutionEditField.Editable = 'off';
            app.BinWidthsLabel = uilabel(app.UIFigure);
            app.BinWidthsLabel.Text = 'Bin Width (s)';
            app.TimeTraceBinWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeTraceBinWidthEditField.Value = 1;
            app.gG2 = uiaxes(app.UIFigure);
            title(app.gG2, 'g2(t)')
            xlabel(app.gG2, 'Delay(ns)')
            ylabel(app.gG2, 'Coincidence Counts')
            app.PlotG2Button = uibutton(app.UIFigure, 'push');
            app.PlotG2Button.ButtonPushedFcn = createCallbackFcn(app, @PlotG2ButtonPushed, true);
            app.PlotG2Button.Text = 'Calculate';
            app.MaxTimeLagEditFieldLabel = uilabel(app.UIFigure);
            app.MaxTimeLagEditFieldLabel.Text = 'Max Time Lag (ns)';
            app.MaxTimeLagEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxTimeLagEditField.Value = 100;
            app.DataFormatEditFieldLabel = uilabel(app.UIFigure);
            app.DataFormatEditFieldLabel.Text = 'Data Format';
            app.DataFormatEditField = uieditfield(app.UIFigure, 'text');
            app.DataFormatEditField.Editable = 'off';
            app.gDecay = uiaxes(app.UIFigure);
            title(app.gDecay, 'PL decay')
            xlabel(app.gDecay, 'Time(ns)')
            ylabel(app.gDecay, {'Counts'; ''})
            app.gDecay.YScale = 'log';
            app.Ch0CountsEditFieldLabel = uilabel(app.UIFigure);
            app.Ch0CountsEditFieldLabel.Text = 'Ch0 Counts';
            app.Ch0CountsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ch0CountsEditField.Editable = 'off';
            app.Ch1CountsEditFieldLabel = uilabel(app.UIFigure);
            app.Ch1CountsEditFieldLabel.Text = 'Ch1 Counts';
            app.Ch1CountsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ch1CountsEditField.Editable = 'off';
            app.OverflowCountsEditFieldLabel = uilabel(app.UIFigure);
            app.OverflowCountsEditFieldLabel.Text = 'Overflow Counts';
            app.OverflowCountsEditField = uieditfield(app.UIFigure, 'numeric');
            app.OverflowCountsEditField.Editable = 'off';
            app.MarkerCountsEditFieldLabel = uilabel(app.UIFigure);
            app.MarkerCountsEditFieldLabel.Text = 'Marker Counts';
            app.MarkerCountsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MarkerCountsEditField.Editable = 'off';
            app.PlotPLdecayButton = uibutton(app.UIFigure, 'push');
            app.PlotPLdecayButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPLdecayButtonPushed, true);
            app.PlotPLdecayButton.Text = 'Calculate';
            app.PeriodnsLabel = uilabel(app.UIFigure);
            app.PeriodnsLabel.Text = 'Period (ns)';
            app.PeriodEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodEditField.Editable = 'off';
            app.ExportPrefixLabel = uilabel(app.UIFigure);
            app.ExportPrefixLabel.Text = 'Export Prefix';
            app.TotalCoindcidenceCountsLabel = uilabel(app.UIFigure);
            app.TotalCoindcidenceCountsLabel.Text = 'Total Coindcidence Counts';
            app.CoincidenceCountsEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoincidenceCountsEditField.Editable = 'off';
            app.BinWidthnsLabel = uilabel(app.UIFigure);
            app.BinWidthnsLabel.Text = 'Bin Width (ns)';
            app.G2BinWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.G2BinWidthEditField.Value = 0.01;
            app.MincpsLabel = uilabel(app.UIFigure);
            app.MincpsLabel.Text = 'Min(cps)';
            app.ThresholdMinCREditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdMinCREditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdMinCREditFieldValueChanged, true);
            app.BinWidthnsLabel_2 = uilabel(app.UIFigure);
            app.BinWidthnsLabel_2.Text = 'Bin Width (ns)';
            app.PLdecayBinWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.PLdecayBinWidthEditField.Value = 0.01;
            app.UseTimeMaskCheckBox = uicheckbox(app.UIFigure);
            app.UseTimeMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseTimeMaskCheckBoxValueChanged, true);
            app.UseTimeMaskCheckBox.Text = 'Use Limits';
            app.UseThresholdCheckBox = uicheckbox(app.UIFigure);
            app.UseThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseThresholdCheckBoxValueChanged, true);
            app.UseThresholdCheckBox.Text = 'Use Threshold';
            app.ExportPLdecayButton = uibutton(app.UIFigure, 'push');
            app.ExportPLdecayButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPLdecayButtonPushed, true);
            app.ExportPLdecayButton.Text = 'Export';
            app.ExportG2Button = uibutton(app.UIFigure, 'push');
            app.ExportG2Button.ButtonPushedFcn = createCallbackFcn(app, @ExportG2ButtonPushed, true);
            app.ExportG2Button.Text = 'Export';
            app.Ch0Ch1CountsEditFieldLabel = uilabel(app.UIFigure);
            app.Ch0Ch1CountsEditFieldLabel.Text = 'Ch0+Ch1 Counts';
            app.Ch0Ch1CountsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Ch0Ch1CountsEditField.Editable = 'off';
            app.MaxcpsLabel = uilabel(app.UIFigure);
            app.MaxcpsLabel.Text = 'Max(cps)';
            app.ThresholdMaxCREditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdMaxCREditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdMaxCREditFieldValueChanged, true);
            app.StepHeightLabel = uilabel(app.UIFigure);
            app.StepHeightLabel.Text = 'Step Height (%) ';
            app.ThresholdStepEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdStepEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdStepEditFieldValueChanged, true);
            app.ThresholdStepEditField.Value = 50;
            app.CustomFileCheckBox = uicheckbox(app.UIFigure);
            app.CustomFileCheckBox.Text = 'Custom';
            app.CustomFileCheckBox.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = PTU_read_v1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end